classdef Sound_Echo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        OpenNewSoundButton            matlab.ui.control.Button
        DomainoptionButtonGroup       matlab.ui.container.ButtonGroup
        FrequencyButton               matlab.ui.control.RadioButton
        TimeButton                    matlab.ui.control.RadioButton
        PlayEchoesSoundButton         matlab.ui.control.Button
        PlayOriginalSoundButton       matlab.ui.control.Button
        AttenuationCoefficientEditField  matlab.ui.control.NumericEditField
        AttenuationCoefficientEditFieldLabel  matlab.ui.control.Label
        NumberofSampleShiftEditField  matlab.ui.control.NumericEditField
        NumberofSampleShiftEditFieldLabel  matlab.ui.control.Label
        NumberofEchoesEditField       matlab.ui.control.NumericEditField
        NumberofEchoesEditFieldLabel  matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private) % Define Some New Parameters
        Button1;
        Button2;
        Button3;   
        file = 'voice.wav';
        path = '';
        Sound;
        Fs;
    end
    
    methods (Access = private)
        
        function DSP0(app) % Define Main Function
            % Read Parameters (User Variables)
            selectedButton = app.DomainoptionButtonGroup.SelectedObject.Text;
            N = app.NumberofEchoesEditField.Value;
            M = app.NumberofSampleShiftEditField.Value;
            alpha = app.AttenuationCoefficientEditField.Value;
            
            %%%%%%%%%%
            % Get New Sound file
            if app.Button3 == 1
                [app.file,app.path] = uigetfile('*.wav');
                app.Button3 = 0;
                [app.Sound , app.Fs] = audioread([app.path app.file]) ;
            end           
            %%%%%%%%%%
            % Plot Original Sound            
            Soundo = (app.Sound)' ;
            n1 = 0 : 1 : length(app.Sound)-1 ;
            [h1 , w1] = freqz(Soundo,1);
            switch selectedButton  
                case "Time"
                    %app.UIAxes.XLabel.String = "Sample";
                    %app.UIAxes.YLabel.String = "Time Amplitude";
                    plot(app.UIAxes,n1/app.Fs,Soundo);                  
                case "Frequency"
                    %app.UIAxes.XLabel.String = "Normalized Frequency (*pi)";
                    %app.UIAxes.YLabel.String = "Frequency Magnitude";
                    plot(app.UIAxes,w1/pi,20*log10(abs(h1)));
            end
            %%%%%%%%%%
            % Echoes Sound Maker
            Sound_Echoes = [zeros(1,M*N+length(Soundo))]; % Define Echo Length
            for k = 0 : N  % Shift & Sum
                for p = 1 : length(Soundo)
                    Sound_Echoes(p+k*M) = Sound_Echoes(p+k*M) + ((alpha)^k).*Soundo(p);
                end
            end
            n2 = 0 : 1 : length(Sound_Echoes)-1 ;
            [h2 , w2] = freqz(Sound_Echoes,1);
            switch selectedButton  %Plot Echoes Sound in Time / Frequency
                case "Time"
                    app.UIAxes2.XLabel.String = "Sample";
                    app.UIAxes2.YLabel.String = "Time Amplitude";
                    plot(app.UIAxes2,n2/app.Fs,Sound_Echoes);
                case "Frequency"
                    app.UIAxes2.XLabel.String = "Normalized Frequency (*pi)";
                    app.UIAxes2.YLabel.String = "Frequency Magnitude";
                    plot(app.UIAxes2,w2/pi,20*log10(abs(h2)));
            end
            %%%%%%%%%%            
            % Play Original Sound
            if app.Button1
                sound(Soundo,app.Fs);
                app.Button1 = 0;
            end
            %%%%%%%%%%
            % Play Echoes Sound
            if app.Button2 == 1
                sound(Sound_Echoes,app.Fs);
                app.Button2 = 0;
            end
            %%%%%%%%%%
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           [app.Sound , app.Fs] = audioread(app.file) ; % Read .wav File in StartUp
            DSP0(app); % Reference to Main Function      
        end

        % Selection changed function: DomainoptionButtonGroup
        function DomainoptionButtonGroupSelectionChanged(app, event)
            DSP0(app); % Reference to Main Function
        end

        % Button pushed function: PlayOriginalSoundButton
        function PlayOriginalSoundButtonPushed(app, event)
            app.Button1 = 1; % Set a Value for Runing this Function in Main Function
            DSP0(app); % Reference to Main Function
        end

        % Button pushed function: PlayEchoesSoundButton
        function PlayEchoesSoundButtonPushed(app, event)
            app.Button2 = 1; % Set a Value for Runing this Function in Main Function
            DSP0(app); % Reference to Main Function
        end

        % Button pushed function: OpenNewSoundButton
        function OpenNewSoundButtonPushed(app, event)
            app.Button3 = 1; % Set a Value for Runing this Function in Main Function
            DSP0(app); % Reference to Main Function
        end

        % Value changed function: NumberofEchoesEditField
        function NumberofEchoesEditFieldValueChanged(app, event)
            DSP0(app); % Reference to Main Function
        end

        % Value changed function: NumberofSampleShiftEditField
        function NumberofSampleShiftEditFieldValueChanged(app, event)
            DSP0(app); % Reference to Main Function
        end

        % Value changed function: AttenuationCoefficientEditField
        function AttenuationCoefficientEditFieldValueChanged(app, event)
            DSP0(app); % Reference to Main Function
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Sound')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Echoes Sound')
            zlabel(app.UIAxes2, 'Z')
            app.NumberofEchoesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofEchoesEditFieldLabel.Text = 'Number of Echoes';
            app.NumberofEchoesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofEchoesEditField.Limits = [0 Inf];
            app.NumberofEchoesEditField.RoundFractionalValues = 'on';
            app.NumberofEchoesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofEchoesEditFieldValueChanged, true);
            app.NumberofEchoesEditField.Value = 3;
            app.NumberofSampleShiftEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofSampleShiftEditFieldLabel.Text = 'Number of Sample Shift';
            app.NumberofSampleShiftEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofSampleShiftEditField.Limits = [0 Inf];
            app.NumberofSampleShiftEditField.ValueDisplayFormat = '%.0f';
            app.NumberofSampleShiftEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofSampleShiftEditFieldValueChanged, true);
            app.NumberofSampleShiftEditField.Value = 44100;
            app.AttenuationCoefficientEditFieldLabel = uilabel(app.UIFigure);
            app.AttenuationCoefficientEditFieldLabel.Text = 'Attenuation Coefficient';
            app.AttenuationCoefficientEditField = uieditfield(app.UIFigure, 'numeric');
            app.AttenuationCoefficientEditField.Limits = [0 1];
            app.AttenuationCoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @AttenuationCoefficientEditFieldValueChanged, true);
            app.AttenuationCoefficientEditField.Value = 0.7;
            app.PlayOriginalSoundButton = uibutton(app.UIFigure, 'push');
            app.PlayOriginalSoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOriginalSoundButtonPushed, true);
            app.PlayOriginalSoundButton.Text = 'Play Original Sound';
            app.PlayEchoesSoundButton = uibutton(app.UIFigure, 'push');
            app.PlayEchoesSoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlayEchoesSoundButtonPushed, true);
            app.PlayEchoesSoundButton.Text = 'Play Echoes Sound';
            app.DomainoptionButtonGroup = uibuttongroup(app.UIFigure);
            app.DomainoptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DomainoptionButtonGroupSelectionChanged, true);
            app.DomainoptionButtonGroup.Title = 'Domain option';
            app.TimeButton = uiradiobutton(app.DomainoptionButtonGroup);
            app.TimeButton.Text = 'Time';
            app.TimeButton.Value = true;
            app.FrequencyButton = uiradiobutton(app.DomainoptionButtonGroup);
            app.FrequencyButton.Text = 'Frequency';
            app.OpenNewSoundButton = uibutton(app.UIFigure, 'push');
            app.OpenNewSoundButton.ButtonPushedFcn = createCallbackFcn(app, @OpenNewSoundButtonPushed, true);
            app.OpenNewSoundButton.Text = 'Open New Sound';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sound_Echo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end